classdef HHG_Scan_Analyzer_V3_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HHG_Scan_Analyzer              matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        DataAnalysisTab                matlab.ui.container.Tab
        Button                         matlab.ui.control.Button
        SelectImageFileLabel           matlab.ui.control.Label
        ImageAxes                      matlab.ui.control.UIAxes
        RunButton                      matlab.ui.control.Button
        LineoutAxes                    matlab.ui.control.UIAxes
        StopButton                     matlab.ui.control.Button
        FastRunCheckBox                matlab.ui.control.CheckBox
        FileNameLabel                  matlab.ui.control.Label
        RunningLabel                   matlab.ui.control.Label
        NoOfImagesEditFieldLabel       matlab.ui.control.Label
        NoOfImagesEditField            matlab.ui.control.NumericEditField
        ProgressLabel                  matlab.ui.control.Label
        SelectScanFileLabel            matlab.ui.control.Label
        Button_2                       matlab.ui.control.Button
        FileNameLabel_2                matlab.ui.control.Label
        outofLabel                     matlab.ui.control.Label
        HCOposAxes                     matlab.ui.control.UIAxes
        CreateMovingAverageButton      matlab.ui.control.Button
        MovingAverageValueSlider       matlab.ui.control.Slider
        CalibrateButton                matlab.ui.control.Button
        UIAxes2                        matlab.ui.control.UIAxes
        CEPWedgeThicknessConversionButton  matlab.ui.control.Button
        MovingAverageValueSliderLabel  matlab.ui.control.Label
        ProminenceEditFieldLabel       matlab.ui.control.Label
        ProminenceEditField            matlab.ui.control.NumericEditField
        Tab2                           matlab.ui.container.Tab
    end


    properties (Access = public)
        I_av % 3d Array for images
        StopRunning = false % Stop Running Variable
        FastRun = false % Fast or Slow Run
        HCO_Pos % Raw HCO Positions
        WedgePositions
        Thickness
        ScanFileLoaded = false;
        wedgeThickness
        im
        CEPconversion = false;
        calibrated = false;
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.FileNameLabel.Visible = 'off';
            app.FileNameLabel_2.Visible = 'off';
            app.RunningLabel.Visible = 'off';
            app.MovingAverageValueSlider.Value = 10;
            app.ProgressLabel.Visible = 'off';
            app.outofLabel.Visible = 'off';
            app.ProminenceEditField.Value = 100;
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.FileNameLabel.Visible = 'on';
            app.FileNameLabel.Text = 'Wait...';
           
            fileName = uigetfile;
            figure(app.HHG_Scan_Analyzer);
            
            if fileName ~= 0
                
                load(fileName);
                app.FileNameLabel.Text = fileName;
    %             app.TextArea.Value = fileName;
                app.I_av = I_av;
                app.MovingAverageValueSlider.Limits = [1 floor(size(app.I_av,3)/10)];
                app.outofLabel.Text = ['/ ',num2str(size(app.I_av,3))];
                app.outofLabel.Visible = 'on';
                app.NoOfImagesEditField.Value = size(app.I_av,3);
                
            else
                app.FileNameLabel.Text = 'No file selected';
            end
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
    
    app.HCO_Pos =[];
    app.calibrated = false;
    app.RunningLabel.Visible = 'on';
    
    if app.FastRun == true
        pause(0.1)
    end
    
    app.ProgressLabel.Visible = 'on';
    
    range = 20;
    
    % Setup window for filtering
    windowSize = 10;
    d = 1;
    c = (1/windowSize)*ones(1,windowSize);


    legend(app.HCOposAxes,'off');
    
    % Pre-allocation
    HCO_locs = zeros(1, app.NoOfImagesEditField.Value);
    
    if app.NoOfImagesEditField.Value > size(app.I_av,3)
        
       app.NoOfImagesEditField.Value = size(app.I_av,3);
        
    end
    
    
    for i = 1:app.NoOfImagesEditField.Value
%     for i = 1:size(app.I_av,3)
    
        % Allows for cancellation at any time
        if app.StopRunning == true
           
            app.StopRunning = false;
            break;
        end
        
        % Running counter
        app.ProgressLabel.Text = [num2str(i),' of ',num2str(app.NoOfImagesEditField.Value)];
        
        %Get Image and lineout (integration over range no. of pixels).
        image = squeeze(app.I_av(:,:,i));
        lineout = sum(image(140:140+range,:))/(range+1);
       
        % Filter out the noise and remove background
        lineout_filtered(i,:) =filter(c,d,lineout);
        [peaks,locations] = findpeaks(lineout_filtered(i,5:end), 'MinPeakProminence',app.ProminenceEditField.Value);
        
        % If no peaks are found, break loop - may need to make stronger
        if isempty(locations) 
            break;
        end
        
        
        % Stores the max peak (taken to be the location of the half-cycle cut-off)
        HCO_locs(i) = max(locations);
        

        % Don't visually plot if fast run checked
        if app.FastRun == false

            imagesc(app.ImageAxes,image(:,5:end));
%            axis(app.ImageAxes,[0 size(image(:,5:end),2) 0 size(image(:,5:end),1)]);#
            axis(app.ImageAxes,'tight');
            drawnow
           
            plot(app.LineoutAxes, 1:length(lineout_filtered(i,5:end)),lineout_filtered(i,5:end),locations,peaks, 'v');
            axis(app.LineoutAxes, [0 length(lineout_filtered(i,5:end)) 0 3500]);
            drawnow 
       
        end
        
    end


    %     imagesc(app.ImageAxes,image(:,5:end));
    %     %            axis(app.ImageAxes,[0 size(image(:,5:end),2) 0 size(image(:,5:end),1)]);#
    %     axis(app.ImageAxes,'tight');
    %     drawnow
    %     
    %     plot(app.LineoutAxes, 1:length(lineout_filtered(i,5:end)),lineout_filtered(i,5:end),locations,peaks, 'v');
    %     axis(app.LineoutAxes, [0 length(lineout_filtered(i,5:end)) 0 3500]);
    %     drawnow 
    %     
        app.HCO_Pos = HCO_locs(HCO_locs ~= 0);
        
        if isempty(app.HCO_Pos)
        
            uialert(app.HHG_Scan_Analyzer,'Prominence Too High','Invalid');
            return;
        else
    
            app.Thickness = 1:length(app.HCO_Pos);
            app.HCOposAxes.XLabel.String = 'Total (Relative) Wedge Thickness (arb. units)';
        
            plot(app.HCOposAxes,app.Thickness(1:length(app.HCO_Pos)), app.HCO_Pos);
            xlim(app.HCOposAxes,[min(app.Thickness(1:length(app.HCO_Pos))) max(app.Thickness(1:length(app.HCO_Pos)))]);


        %     imagesc(app.UIAxes2,flipud(lineout_filtered'));
            app.im = imagesc(app.UIAxes2,lineout_filtered');
            set(app.UIAxes2,'YDir','normal')
            axis(app.UIAxes2, 'tight');
            app.UIAxes2.XLabel.String = 'Total (Relative) Wedge Thickness (arb. units)';
        end
    
    app.RunningLabel.Visible = 'off';
    app.ProgressLabel.Visible = 'off';

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.StopRunning = true;
        end

        % Value changed function: FastRunCheckBox
        function FastRunCheckBoxValueChanged(app, event)
          app.FastRun = app.FastRunCheckBox.Value;
        end

        % Button pushed function: CreateMovingAverageButton
        function CreateMovingAverageButtonPushed(app, event)
            HCO_locs_movaverag = movmean(app.HCO_Pos,app.MovingAverageValueSlider.Value);
    %      plot(app.HCOposAxes,1:length(HCO_locs), HCO_locs,1:length(HCO_locs_movaverag),HCO_locs_movaverag,1:length(moveaveragemov),moveaveragemov);
         plot(app.HCOposAxes,app.Thickness(1:length(app.HCO_Pos)), app.HCO_Pos);
         hold(app.HCOposAxes, 'on');
         plot(app.HCOposAxes,app.Thickness,HCO_locs_movaverag, 'linewidth',2);
         xlim(app.HCOposAxes,[min(app.Thickness(1:length(app.HCO_Pos))) max(app.Thickness(1:length(app.HCO_Pos)))]);
         legend(app.HCOposAxes,'Values','Moving Average');
         hold(app.HCOposAxes, 'off');
         
    %      app.HCOposAxes(2).LineWidth = 2;
        end

        % Value changed function: MovingAverageValueSlider
        function MovingAverageValueSliderValueChanged(app, event)
            HCO_locs_movaverag = movmean(app.HCO_Pos,app.MovingAverageValueSlider.Value);
    %      plot(app.HCOposAxes,1:length(HCO_locs), HCO_locs,1:length(HCO_locs_movaverag),HCO_locs_movaverag,1:length(moveaveragemov),moveaveragemov);
         plot(app.HCOposAxes,app.Thickness(1:length(app.HCO_Pos)), app.HCO_Pos);
         hold(app.HCOposAxes, 'on');
         plot(app.HCOposAxes,app.Thickness,HCO_locs_movaverag, 'linewidth',2);
         xlim(app.HCOposAxes,[min(app.Thickness(1:length(app.HCO_Pos))) max(app.Thickness(1:length(app.HCO_Pos)))]);
         legend(app.HCOposAxes,'Values','Moving Average');
         hold(app.HCOposAxes, 'off');
         
%          
%          hold(app.UIAxes2, 'on');
%          
%          moveaverageplot = plot(app.UIAxes2,app.Thickness,HCO_locs_movaverag, 'linewidth',2);
%          hold(app.UIAxes2, 'off');
         
            
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.FileNameLabel_2.Visible = 'on';
            app.FileNameLabel_2.Text = 'Wait...';
            pause(0.1);
            fileName = uigetfile;
            figure(app.HHG_Scan_Analyzer);
            
           if fileName ~= 0 
                load(fileName);
                app.FileNameLabel_2.Text = fileName;
    %             app.TextArea.Value = fileName;
                app.WedgePositions = WedgePositions;
                app.ScanFileLoaded = true;
                
           else
               app.FileNameLabel_2.Text = 'No file selected';
           end
        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)
            if app.ScanFileLoaded == true
            
            
                app.Thickness = 2.*((0.8 .* (app.WedgePositions(1:length(app.HCO_Pos))-min(app.WedgePositions))/200).* tand(2.3) + 0.1);
            
                plot(app.HCOposAxes,app.Thickness, app.HCO_Pos);
                xlim(app.HCOposAxes,[min(app.Thickness) max(app.Thickness)]);
                app.HCOposAxes.XLabel.String = 'Total (Relative) Wedge Thickness (mm)';
                
                set(app.im, 'XData', app.Thickness);
                app.UIAxes2.XLabel.String = 'Total (Relative) Wedge Thickness (mm)';
    %             axis tight
    
                app.calibrated = true;
            
            else
                
%                 fig = app.HHG_Scan_Analyzer;
                uialert(app.HHG_Scan_Analyzer,'Please Select Scan File','Invalid Data');
                
            end
            
            
        end

        % Button pushed function: CEPWedgeThicknessConversionButton
        function CEPWedgeThicknessConversionButtonPushed(app, event)
            if app.calibrated == true
            
                if app.CEPconversion == false
                    
                    app.Thickness = app.Thickness ./ (0.0491./2);
                    plot(app.HCOposAxes,app.Thickness, app.HCO_Pos);
                    xlim(app.HCOposAxes,[min(app.Thickness) max(app.Thickness)]);
                    app.HCOposAxes.XLabel.String = 'CEP (relative) (\pi rads)';
                    
                    set(app.im, 'XData', app.Thickness);
                    app.UIAxes2.XLabel.String = 'CEP (relative) (\pi rads)';
                    app.CEPconversion = true;
                    
                else
                    
                    app.Thickness = app.Thickness .* (0.0491./2);
                    plot(app.HCOposAxes,app.Thickness, app.HCO_Pos);
                    xlim(app.HCOposAxes,[min(app.Thickness) max(app.Thickness)]);
                    app.HCOposAxes.XLabel.String = 'Total (Relative) Wedge Thickness (mm)';
                    
                    set(app.im, 'XData', app.Thickness);
                    app.UIAxes2.XLabel.String = 'Total (Relative) Wedge Thickness (mm)';
                    app.CEPconversion = false;


                end
                
            end
                 
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HHG_Scan_Analyzer = uifigure;
            app.HHG_Scan_Analyzer.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.HHG_Scan_Analyzer);
            app.DataAnalysisTab = uitab(app.TabGroup);
            app.DataAnalysisTab.Title = 'Data Analysis';
            app.Button = uibutton(app.DataAnalysisTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '...';
            app.SelectImageFileLabel = uilabel(app.DataAnalysisTab);
            app.SelectImageFileLabel.Text = 'Select Image File';
            app.ImageAxes = uiaxes(app.DataAnalysisTab);
            title(app.ImageAxes, 'Image')
            xlabel(app.ImageAxes, 'Energy (arb. units)')
            ylabel(app.ImageAxes, 'Y')
            app.RunButton = uibutton(app.DataAnalysisTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.LineoutAxes = uiaxes(app.DataAnalysisTab);
            title(app.LineoutAxes, 'Lineout')
            xlabel(app.LineoutAxes, 'Energy (arb. units)')
            ylabel(app.LineoutAxes, 'Y')
            app.StopButton = uibutton(app.DataAnalysisTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.FastRunCheckBox = uicheckbox(app.DataAnalysisTab);
            app.FastRunCheckBox.ValueChangedFcn = createCallbackFcn(app, @FastRunCheckBoxValueChanged, true);
            app.FastRunCheckBox.Text = 'Fast Run?';
            app.FileNameLabel = uilabel(app.DataAnalysisTab);
            app.FileNameLabel.Text = 'File Name';
            app.RunningLabel = uilabel(app.DataAnalysisTab);
            app.RunningLabel.Text = 'Running...';
            app.NoOfImagesEditFieldLabel = uilabel(app.DataAnalysisTab);
            app.NoOfImagesEditFieldLabel.Text = 'No. Of Images';
            app.NoOfImagesEditField = uieditfield(app.DataAnalysisTab, 'numeric');
            app.ProgressLabel = uilabel(app.DataAnalysisTab);
            app.ProgressLabel.Text = 'Progress';
            app.SelectScanFileLabel = uilabel(app.DataAnalysisTab);
            app.SelectScanFileLabel.Text = 'Select Scan File';
            app.Button_2 = uibutton(app.DataAnalysisTab, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '...';
            app.FileNameLabel_2 = uilabel(app.DataAnalysisTab);
            app.FileNameLabel_2.Text = 'File Name';
            app.outofLabel = uilabel(app.DataAnalysisTab);
            app.outofLabel.Text = 'out of';
            app.HCOposAxes = uiaxes(app.DataAnalysisTab);
            title(app.HCOposAxes, 'HCO Position')
            xlabel(app.HCOposAxes, 'Total (Relative) Wedge Thickness (mm)')
            ylabel(app.HCOposAxes, 'Energy (pixels)')
            app.CreateMovingAverageButton = uibutton(app.DataAnalysisTab, 'push');
            app.CreateMovingAverageButton.ButtonPushedFcn = createCallbackFcn(app, @CreateMovingAverageButtonPushed, true);
            app.CreateMovingAverageButton.Text = 'Create Moving Average';
            app.MovingAverageValueSlider = uislider(app.DataAnalysisTab);
            app.MovingAverageValueSlider.ValueChangedFcn = createCallbackFcn(app, @MovingAverageValueSliderValueChanged, true);
            app.CalibrateButton = uibutton(app.DataAnalysisTab, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Text = 'Calibrate';
            app.UIAxes2 = uiaxes(app.DataAnalysisTab);
            title(app.UIAxes2, 'Total Lineout')
            xlabel(app.UIAxes2, 'Total (Relative) Wedge Thickness (arb. units)')
            ylabel(app.UIAxes2, 'Energy (Pixels)')
            app.CEPWedgeThicknessConversionButton = uibutton(app.DataAnalysisTab, 'push');
            app.CEPWedgeThicknessConversionButton.ButtonPushedFcn = createCallbackFcn(app, @CEPWedgeThicknessConversionButtonPushed, true);
            app.CEPWedgeThicknessConversionButton.Text = {'CEP / Wedge Thickness'; 'Conversion'};
            app.MovingAverageValueSliderLabel = uilabel(app.DataAnalysisTab);
            app.MovingAverageValueSliderLabel.Text = 'Moving Average Value';
            app.ProminenceEditFieldLabel = uilabel(app.DataAnalysisTab);
            app.ProminenceEditFieldLabel.Text = 'Prominence';
            app.ProminenceEditField = uieditfield(app.DataAnalysisTab, 'numeric');
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
        end
    end

    methods (Access = public)

        % Construct app
        function app = HHG_Scan_Analyzer_V3_1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HHG_Scan_Analyzer)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HHG_Scan_Analyzer)
        end
    end
end